classdef gui < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout            matlab.ui.container.GridLayout
        MoveRobotButton       matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        GridLayout3           matlab.ui.container.GridLayout
        ForceEditField        matlab.ui.control.NumericEditField
        EnterexternalforceontheendeffectorLabel  matlab.ui.control.Label
        ExternalforceLabel    matlab.ui.control.Label
        PositioncontrolLabel  matlab.ui.control.Label
        GridLayout2_2         matlab.ui.container.GridLayout
        YEditField_2          matlab.ui.control.NumericEditField
        YLabel_2              matlab.ui.control.Label
        PEditField            matlab.ui.control.NumericEditField
        PLabel                matlab.ui.control.Label
        REditField            matlab.ui.control.NumericEditField
        RLabel                matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        ZEditField            matlab.ui.control.NumericEditField
        ZLabel                matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        YLabel                matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        XLabel                matlab.ui.control.Label
    end

    properties (Access = public)
        currentQ (1,6) = zeros(1,6); % Array of current joint values
        force double = 0; % External force applied as a wrench at the end effector
        pitch double = 0; % pitch orientation of the end effector
        robot % Robot
        roll double = 0; % roll orientation of the end effector
        x double = 0.4521; % x position of the end effector
        y double = -0.15; % y position of the end effector
        yaw double = 0; % yaw orientation of the end effector
        z double = 0.4318; % z position of the end effector
    end
    
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % clear, close all, clc;
            comp.robot = make_robot();
            % Starting here, adjust to use IK to convert find target joint
            % positions (as targetQ) from starting values currentQ. This
            % will make it easier to plot movement.
            % IK SOLVER
            % Ends here. params should take in the two joint arrays (or
            % just adjust to take them in as pure variables since thats
            % probably easier
            target = [comp.x, comp.y, comp.z, comp.roll,comp.pitch,comp.yaw];
            [jointPos, jointVel, jointAcc, jointTau, t_s]  = move_robot(comp.robot, comp.currentQ, target, comp.force);
            if ~isempty(jointPos)
                p0 = subplot(4,2,[1,3,5,7]);
                hold off;
                set(gcf, 'Name', 'PUMA 560 Simulator');
                comp.robot.plot(jointPos(:,1:10:end)');
                p1 = subplot(4,2,2);
                cla(p1);
                hold on, grid on;
                joint_pos_plots(1) = plot(t_s,jointPos(1,:), 'r', 'Linewidth', 2);
                joint_pos_plots(2) = plot(t_s,jointPos(2,:), 'g', 'Linewidth', 2);
                joint_pos_plots(3) = plot(t_s,jointPos(3,:), 'b', 'Linewidth', 2);
                joint_pos_plots(4) = plot(t_s,jointPos(4,:), 'c', 'Linewidth', 2);
                joint_pos_plots(5) = plot(t_s,jointPos(5,:), 'm', 'Linewidth', 2);
                joint_pos_plots(6) = plot(t_s,jointPos(6,:), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Positions');
                xlabel("Time (s)");
                ylabel("Position (rad)");
                % set(gca, 'FontSize', 14);
                % hold off;
                p2 = subplot(4,2,4);
                cla(p2);
                hold on, grid on;
                joint_vel_plots(1) = plot(t_s,jointVel(1,:), 'r', 'Linewidth', 2);
                joint_vel_plots(2) = plot(t_s,jointVel(2,:), 'g', 'Linewidth', 2);
                joint_vel_plots(3) = plot(t_s,jointVel(3,:), 'b', 'Linewidth', 2);
                joint_vel_plots(4) = plot(t_s,jointVel(4,:), 'c', 'Linewidth', 2);
                joint_vel_plots(5) = plot(t_s,jointVel(5,:), 'm', 'Linewidth', 2);
                joint_vel_plots(6) = plot(t_s,jointVel(6,:), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Velocities');
                xlabel("Time (s)");
                ylabel("Velocity (rad/s)");
                % set(gca, 'FontSize', 14);
                % hold off;
                p3 = subplot(4,2,6);
                cla(p3);
                hold on, grid on;
                joint_acc_plots(1) = plot(t_s,jointAcc(1,:), 'r', 'Linewidth', 2);
                joint_acc_plots(2) = plot(t_s,jointAcc(2,:), 'g', 'Linewidth', 2);
                joint_acc_plots(3) = plot(t_s,jointAcc(3,:), 'b', 'Linewidth', 2);
                joint_acc_plots(4) = plot(t_s,jointAcc(4,:), 'c', 'Linewidth', 2);
                joint_acc_plots(5) = plot(t_s,jointAcc(5,:), 'm', 'Linewidth', 2);
                joint_acc_plots(6) = plot(t_s,jointAcc(6,:), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Accelerations');
                xlabel("Time (s)");
                ylabel("Acceleration (rad/s^2)");
                % set(gca, 'FontSize', 14);
                % hold off;
                p4 = subplot(4,2,8);
                cla(p4);
                hold on, grid on;
                joint_tau_plots(1) = plot(t_s,jointTau(1,:), 'r', 'Linewidth', 2);
                joint_tau_plots(2) = plot(t_s,jointTau(2,:), 'g', 'Linewidth', 2);
                joint_tau_plots(3) = plot(t_s,jointTau(3,:), 'b', 'Linewidth', 2);
                joint_tau_plots(4) = plot(t_s,jointTau(4,:), 'c', 'Linewidth', 2);
                joint_tau_plots(5) = plot(t_s,jointTau(5,:), 'm', 'Linewidth', 2);
                joint_tau_plots(6) = plot(t_s,jointTau(6,:), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Torques');
                xlabel("Time (s)");
                ylabel("Torque (Nm)");
                title('Joint Torques');
                % set(gca, 'FontSize', 14);
                hold off;
                comp.currentQ = jointPos(:, end);
            else
                errordlg('\fontsize{30} Inverse kinematics failed for that pose', 'IKError', 'modal')
            end
        end

        % Value changed function: XEditField
        function updateX(comp, event)
            comp.x = comp.XEditField.Value;
            
        end

        % Value changed function: YEditField
        function updateY(comp, event)
            comp.y = comp.YEditField.Value;
            
        end

        % Value changed function: ZEditField
        function updateZ(comp, event)
            comp.z = comp.ZEditField.Value;
            
        end

        % Value changed function: REditField
        function updateRoll(comp, event)
            comp.roll = comp.REditField.Value;
            
        end

        % Value changed function: PEditField
        function updatePitch(comp, event)
            comp.pitch = comp.PEditField.Value;
        end

        % Value changed function: YEditField_2
        function updateYaw(comp, event)
            comp.yaw = comp.YEditField_2.Value;
        end

        % Value changed function: ForceEditField
        function updateForce(comp, event)
            comp.force = comp.ForceEditField.Value;
            
        end

        % Button pushed function: ResetButton
        function resetSim(comp, event)
            comp.robot = make_robot();
            comp.x = 0.4521;
            comp.y = -0.15;
            comp.z = 0.4318;
            comp.roll = 0;
            comp.pitch = 0;
            comp.yaw = 0;
            comp.force = 0;
            comp.currentQ = zeros(1,6);
            comp.XEditField.Value = comp.x;
            comp.YEditField.Value = comp.y;
            comp.ZEditField.Value = comp.z;
            comp.REditField.Value = 0;
            comp.PEditField.Value = 0;
            comp.YEditField_2.Value = 0;
            comp.ForceEditField.Value = 0;
            comp.moveRobot()
        end

        % Button pushed function: MoveRobotButton
        function moveRobot(comp, event)
            % Starting here, adjust to use IK to convert find target joint
            % positions (as targetQ) from starting values currentQ. This
            % will make it easier to plot movement.
            % IK SOLVER
            % Ends here. params should take in the two joint arrays (or
            % just adjust to take them in as pure variables since thats
            % probably easier
            target = [comp.x, comp.y, comp.z, comp.roll,comp.pitch,comp.yaw];
            [jointPos, jointVel, jointAcc, jointTau, t_s]  = move_robot(comp.robot, comp.currentQ, target, comp.force);
            if ~isempty(jointPos)
                p0 = subplot(4,2,[1,3,5,7]);
                hold off;
                set(gcf, 'Name', 'PUMA 560 Simulator');
                % comp.robot.plot(jointPos(:,1:10:end)');
                p1 = subplot(4,2,2);
                cla(p1);
                hold on, grid on;
                plot([],[], 'r', 'Linewidth', 2)
                joint_pos_plots(1) = plot([0],[0], 'r', 'Linewidth', 2);
                joint_pos_plots(2) = plot([0],[0], 'g', 'Linewidth', 2);
                joint_pos_plots(3) = plot([0],[0], 'b', 'Linewidth', 2);
                joint_pos_plots(4) = plot([0],[0], 'c', 'Linewidth', 2);
                joint_pos_plots(5) = plot([0],[0], 'm', 'Linewidth', 2);
                joint_pos_plots(6) = plot([0],[0], 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Positions');
                xlabel("Time (s)");
                ylabel("Position (rad)");
                xlim([t_s(1), t_s(end)]);
                % set(gca, 'FontSize', 14);
                % hold off;
                p2 = subplot(4,2,4);
                cla(p2);
                hold on, grid on;
                joint_vel_plots(1) = plot([0],[0], 'r', 'Linewidth', 2);
                joint_vel_plots(2) = plot([0],[0], 'g', 'Linewidth', 2);
                joint_vel_plots(3) = plot([0],[0], 'b', 'Linewidth', 2);
                joint_vel_plots(4) = plot([0],[0], 'c', 'Linewidth', 2);
                joint_vel_plots(5) = plot([0],[0], 'm', 'Linewidth', 2);
                joint_vel_plots(6) = plot([0],[0], 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Velocities');
                xlabel("Time (s)");
                ylabel("Velocity (rad/s)");
                xlim([t_s(1), t_s(end)]);
                % set(gca, 'FontSize', 14);
                % hold off;
                p3 = subplot(4,2,6);
                cla(p3);
                hold on, grid on;
                joint_acc_plots(1) = plot([0],[0], 'r', 'Linewidth', 2);
                joint_acc_plots(2) = plot([0],[0], 'g', 'Linewidth', 2);
                joint_acc_plots(3) = plot([0],[0], 'b', 'Linewidth', 2);
                joint_acc_plots(4) = plot([0],[0], 'c', 'Linewidth', 2);
                joint_acc_plots(5) = plot([0],[0], 'm', 'Linewidth', 2);
                joint_acc_plots(6) = plot([0],[0], 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Accelerations');
                xlabel("Time (s)");
                ylabel("Acceleration (rad/s^2)");
                xlim([t_s(1), t_s(end)]);
                % set(gca, 'FontSize', 14);
                % hold off;
                p4 = subplot(4,2,8);
                cla(p4);
                hold on, grid on;
                joint_tau_plots(1) = plot([0],[0], 'r', 'Linewidth', 2);
                joint_tau_plots(2) = plot([0],[0], 'g', 'Linewidth', 2);
                joint_tau_plots(3) = plot([0],[0], 'b', 'Linewidth', 2);
                joint_tau_plots(4) = plot([0],[0], 'c', 'Linewidth', 2);
                joint_tau_plots(5) = plot([0],[0], 'm', 'Linewidth', 2);
                joint_tau_plots(6) = plot([0],[0], 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Torques');
                xlabel("Time (s)");
                ylabel("Torques (Nm)");
                xlim([t_s(1), t_s(end)]);
                % set(gca, 'FontSize', 14);
                hold off;
                for i=1:25:size(jointPos, 2)
                    % Update pos, vel, acc, and tau for each graph
                    subplot(4,2,[1,3,5,7]);
                    for j=1:6
                        set(joint_pos_plots(j), 'XData', t_s(1:i), 'YData', jointPos(j, 1:i));
                        set(joint_vel_plots(j), 'XData', t_s(1:i), 'YData', jointVel(j, 1:i));
                        set(joint_acc_plots(j), 'XData', t_s(1:i), 'YData', jointAcc(j, 1:i));
                        set(joint_tau_plots(j), 'XData', t_s(1:i), 'YData', jointTau(j, 1:i));
                        comp.robot.plot(jointPos(:, i)');
                    end
                end
                comp.currentQ = jointPos(:, end);
            else
                errordlg('Inverse kinematics failed for that pose', 'IKError', 'modal')
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 351 303];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.XLabel = uilabel(comp.GridLayout2);
            comp.XLabel.Text = 'X:';
            comp.XEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.XEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateX, true);
            comp.XEditField.Value = 0.4521;
            comp.YLabel = uilabel(comp.GridLayout2);
            comp.YLabel.Text = 'Y:';
            comp.YEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.YEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateY, true);
            comp.YEditField.Value = -0.15;
            comp.ZLabel = uilabel(comp.GridLayout2);
            comp.ZLabel.Text = 'Z:';
            comp.ZEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.ZEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateZ, true);
            comp.ZEditField.Value = 0.4318;
            comp.GridLayout2_2 = uigridlayout(comp.GridLayout);
            comp.RLabel = uilabel(comp.GridLayout2_2);
            comp.RLabel.Text = 'R:';
            comp.REditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.REditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateRoll, true);
            comp.PLabel = uilabel(comp.GridLayout2_2);
            comp.PLabel.Text = 'P:';
            comp.PEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.PEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updatePitch, true);
            comp.YLabel_2 = uilabel(comp.GridLayout2_2);
            comp.YLabel_2.Text = 'Y:';
            comp.YEditField_2 = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.YEditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateYaw, true);
            comp.PositioncontrolLabel = uilabel(comp.GridLayout);
            comp.PositioncontrolLabel.Text = 'Position control:';
            comp.ExternalforceLabel = uilabel(comp.GridLayout);
            comp.ExternalforceLabel.Text = 'External force:';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.EnterexternalforceontheendeffectorLabel = uilabel(comp.GridLayout3);
            comp.EnterexternalforceontheendeffectorLabel.Text = 'Enter external force on the end effector:';
            comp.ForceEditField = uieditfield(comp.GridLayout3, 'numeric');
            comp.ForceEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateForce, true);
            comp.ResetButton = uibutton(comp.GridLayout, 'push');
            comp.ResetButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @resetSim, true);
            comp.ResetButton.Text = 'Reset';
            comp.MoveRobotButton = uibutton(comp.GridLayout, 'push');
            comp.MoveRobotButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @moveRobot, true);
            comp.MoveRobotButton.Text = 'Move Robot';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end